classdef The_Sotware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SelectSourceImagesButton  matlab.ui.control.Button
        GenerateImageButton       matlab.ui.control.Button
        SelectTargetImageButton   matlab.ui.control.Button
        PictureFrame              matlab.ui.control.UIAxes
        WidthLabel                matlab.ui.control.Label
        WidthEditField            matlab.ui.control.NumericEditField
        OutputResolutionLabel     matlab.ui.control.Label
        HeightEditFieldLabel      matlab.ui.control.Label
        HeightEditField           matlab.ui.control.NumericEditField
        TilesLabel                matlab.ui.control.Label
        HorizontalEditFieldLabel  matlab.ui.control.Label
        HorizontalTilesField      matlab.ui.control.NumericEditField
        VerticalEditFieldLabel    matlab.ui.control.Label
        VerticalTilesField        matlab.ui.control.NumericEditField
        ErrorMessage              matlab.ui.control.Label
    end

    
    properties (Access = public)
        imgSetPath; % stores path to folder
        baseImgPath; % path to image to be mosaiced
        
        targetImage;
        targetImageLoaded = false;
        
        pixelWidth = 0; % width of the output image
        pixelHeight = 0; % height of the output image
        horTiles = 0; % number of horizontal tiles
        vertTiles = 0 % number of vertical tiles
        
        trainingSet % set of images to be used as tiles
        trainingSetLoaded = false; % stores whether or not a training set has been loaded
        
        canGenerate = false; % whether or not the resolution is positive and divisible by the number of tiles
    end
    
    methods (Access = public)
        
        function ValidateInputs(app)
            if app.pixelHeight <= 0 || app.pixelWidth <= 0|| app.horTiles <= 0 || app.vertTiles <= 0
                app.canGenerate = false;
            elseif (mod(app.pixelHeight, app.vertTiles) || mod(app.pixelWidth, app.horTiles))
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('The resolution is not divisible by the \nnumber of tiles');
            elseif (app.trainingSetLoaded == false)
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load a training set');
            elseif (app.targetImageLoaded == false)
                app.canGenerate = false;
                app.ErrorMessage.FontColor = [1 0 0];
                app.ErrorMessage.Text = sprintf('Please load a training set');
            else
                app.canGenerate = true;
                app.ErrorMessage.FontColor = [0 0 0];
                app.ErrorMessage.Text = "Ready to generate mosaic";
            end
        end
    
        function y = TileResolution(app, picRes, tileRes)
            y = picRes / tileRes;
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectSourceImagesButton
        function SelectSourceImagesButtonPushed(app, event)
            app.imgSetPath = uigetdir+"\";
            app.trainingSet = filesRead(app.imgSetPath);
            app.trainingSetLoaded = true;
            ValidateInputs(app);
        end

        % Button pushed function: GenerateImageButton
        function GenerateImageButtonPushed(app, event)
            if(app.canGenerate == true)
                % Code to generate the mosaic and open it in a new window
                tileX = TileResolution(app, app.pixelWidth, app.horTiles);
                tileY = TileResolution(app, app.pixelHeight, app.vertTiles);
                
                % Resize target image
                app.targetImage = imresize(app.targetImage, [app.pixelHeight, app.pixelWidth]);
                
                app.trainingSet = prepare_images(app.trainingSet, tileX, tileY);
                figure, imshow(pic_built(app.targetImage, app.trainingSet, tileY, tileX, app.vertTiles, app.horTiles));
            end
        end

        % Button pushed function: SelectTargetImageButton
        function SelectTargetImageButtonPushed(app, event)
            [file, path] = uigetfile;
            figure(app.UIFigure);
            app.targetImage = imread(fullfile(path, file));
            imshow(fullfile(path, file), 'Parent', app.PictureFrame);
            app.targetImageLoaded = true;
            ValidateInputs(app);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.pixelHeight = app.HeightEditField.Value;
            ValidateInputs(app);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.pixelWidth = app.WidthEditField.Value;
            ValidateInputs(app)
        end

        % Value changed function: HorizontalTilesField
        function HorizontalTilesFieldValueChanged(app, event)
            app.horTiles = app.HorizontalTilesField.Value;
            ValidateInputs(app)
        end

        % Value changed function: VerticalTilesField
        function VerticalTilesFieldValueChanged(app, event)
            app.vertTiles = app.VerticalTilesField.Value;
            ValidateInputs  (app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectSourceImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectSourceImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSourceImagesButtonPushed, true);
            app.SelectSourceImagesButton.Text = 'Select Source Images';
            app.GenerateImageButton = uibutton(app.UIFigure, 'push');
            app.GenerateImageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateImageButtonPushed, true);
            app.GenerateImageButton.Text = 'Generate Image';
            app.SelectTargetImageButton = uibutton(app.UIFigure, 'push');
            app.SelectTargetImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetImageButtonPushed, true);
            app.SelectTargetImageButton.Text = 'Select Target Image';
            app.PictureFrame = uiaxes(app.UIFigure);
            title(app.PictureFrame, '')
            app.PictureFrame.XTick = [];
            app.PictureFrame.YTick = [];
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.OutputResolutionLabel = uilabel(app.UIFigure);
            app.OutputResolutionLabel.Text = 'Output Resolution';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.TilesLabel = uilabel(app.UIFigure);
            app.TilesLabel.Text = 'No. of Tiles';
            app.HorizontalEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalTilesField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalTilesField.ValueChangedFcn = createCallbackFcn(app, @HorizontalTilesFieldValueChanged, true);
            app.VerticalEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalTilesField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalTilesField.ValueChangedFcn = createCallbackFcn(app, @VerticalTilesFieldValueChanged, true);
            app.ErrorMessage = uilabel(app.UIFigure);
            app.ErrorMessage.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = The_Sotware

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end